classdef Final_Project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        XEditField         matlab.ui.control.NumericEditField
        YEditField         matlab.ui.control.NumericEditField
        PlotButton         matlab.ui.control.Button
        GraphSwitchLabel   matlab.ui.control.Label
        GraphSwitch        matlab.ui.control.Switch
        Lamp               matlab.ui.control.Lamp
        LegendButtonGroup  matlab.ui.container.ButtonGroup
        Button             matlab.ui.control.RadioButton
        Button_2           matlab.ui.control.RadioButton
        Button_3           matlab.ui.control.RadioButton
        Lamp3              matlab.ui.control.Lamp
        ClearGraphButton   matlab.ui.control.Button
        XAxisEditField     matlab.ui.control.EditField
        YAxisEditField     matlab.ui.control.EditField
        TitleEditField     matlab.ui.control.EditField
        XEditField_2       matlab.ui.control.NumericEditField
        YEditField_2       matlab.ui.control.NumericEditField
        Input1SwitchLabel  matlab.ui.control.Label
        Input1Switch       matlab.ui.control.Switch
        Input2SwitchLabel  matlab.ui.control.Label
        Input2Switch       matlab.ui.control.Switch
        Lamp_2             matlab.ui.control.Lamp
        Lamp2              matlab.ui.control.Lamp
        EditField          matlab.ui.control.EditField
        EditField_2        matlab.ui.control.EditField
        EditField_3        matlab.ui.control.EditField
        EditField_4        matlab.ui.control.EditField
        EditableLabel      matlab.ui.control.Label
        EditableLabel_2    matlab.ui.control.Label
        EditableLabel_3    matlab.ui.control.Label
        EditableLabel_4    matlab.ui.control.Label
        EditableLabel_5    matlab.ui.control.Label
        EditableLabel_6    matlab.ui.control.Label
        EditableLabel_7    matlab.ui.control.Label
        NOTEAllSwitchesmuchbeintheOnpositionwhenchangingLegendLabel  matlab.ui.control.Label
        UIAxes             matlab.ui.control.UIAxes
        ContextMenu        matlab.ui.container.ContextMenu
        Menu               matlab.ui.container.Menu
        Menu2              matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
%Cord 1
            x=app.XEditField.Value;
            y=app.YEditField.Value;
            a=app.XEditField_2.Value;
            b=app.YEditField_2.Value;
           
            val = app.GraphSwitch.Value;                    
            value = app.Input1Switch.Value;
            value2 = app.Input2Switch.Value;
            
            disp1 = app.Button.Value;                    
            disp2 = app.Button_2.Value;   
            disp3 = app.Button_3.Value; 
            
%Legend Lamp
            if strcmpi(val, 'On')==1 && strcmpi(value,'On') && strcmpi(value2,'Off')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value,'Off') && strcmpi(value2,'On')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value2,'On') && strcmpi(value,'On')
                app.Lamp3.Color = 'g';

                
            else
                app.Lamp3.Color = 'r';  
                   
            end 
            
%disp1            
            if strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00]) 
            
            elseif strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])  
            
            elseif strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])  
            
%disp2  
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])  
            
%disp3            
            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])

            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])

            else 
                plot(x,y,a,b,'parent',app.UIAxes)
            end

         
        end

        % Value changed function: GraphSwitch
        function GraphSwitchValueChanged(app, event)
            val = app.GraphSwitch.Value;
            value = app.Input1Switch.Value;
            value2 = app.Input2Switch.Value;
            
            disp1 = app.Button.Value;                    
            disp2 = app.Button_2.Value;   
            disp3 = app.Button_3.Value; 
            if strcmpi(val, 'On')==1
                app.Lamp.Color = 'g';
            else
                app.Lamp.Color = 'r';  
                   
            end   
            
%Legend Lamp
            if strcmpi(val, 'On')==1 && strcmpi(value,'On') && strcmpi(value2,'Off')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value,'Off') && strcmpi(value2,'On')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value2,'On') && strcmpi(value,'On')
                app.Lamp3.Color = 'g';

                
            else
                app.Lamp3.Color = 'r';  
                   
            end   
%Variables
            x=app.XEditField.Value;
            y=app.YEditField.Value;
            
            a=app.XEditField_2.Value;
            b=app.YEditField_2.Value;
            val = app.GraphSwitch.Value;            
%disp1            

            
            if strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00]) 
            
            elseif strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])  
            
            elseif strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])  
            
%disp2            
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])  
%disp3            
            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])

            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])
            
%else
            else 
                plot(x,y,a,b,'parent',app.UIAxes)
            end
          
        end

        % Selection changed function: LegendButtonGroup
        function LegendButtonGroupSelectionChanged(app, event)
            %selectedButton = app.LegendButtonGroup.SelectedObject;
            x=app.XEditField.Value;
            y=app.YEditField.Value;
            a=app.XEditField_2.Value;
            b=app.YEditField_2.Value;
            
            val = app.GraphSwitch.Value;
            value = app.Input1Switch.Value;
            value2 = app.Input2Switch.Value;
            
            disp1 = app.Button.Value;                    
            disp2 = app.Button_2.Value;  
            disp3 = app.Button_3.Value; 
%Legend Lamp
            if strcmpi(val, 'On')==1 && strcmpi(value,'On') && strcmpi(value2,'Off')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value,'Off') && strcmpi(value2,'On')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value2,'On') && strcmpi(value,'On')
                app.Lamp3.Color = 'g';

                
            else
                app.Lamp3.Color = 'r';  
                   
            end          
            
%disp1            

            
            if strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00]) 
            
            elseif strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])  
            
            elseif strcmpi(val,'On')==1 && disp1==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])  
            
%disp2            
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp2==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])  
%disp3            
            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'On') && strcmpi(value2,'On')
            plot(x,y,a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])

            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'On') && strcmpi(value2,'Off')
            plot(x,y,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])
            
            elseif strcmpi(val,'On')==1 && disp3==true && strcmpi(value,'Off') && strcmpi(value2,'On')
            plot(a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])
            
%else            
            else 
                plot(x,y,a.b,'parent',app.UIAxes)
            end
           
        end

        % Button pushed function: ClearGraphButton
        function ClearGraphButtonPushed(app, event)
            x=app.XEditField.Value;
            y=app.YEditField.Value;
            a=app.XEditField_2.Value;
            b=app.YEditField_2.Value;
            
            app.XEditField.Value=0;
            app.YEditField.Value=0;
            
            app.XEditField_2.Value=0;
            app.YEditField_2.Value=0;
            val = app.GraphSwitch.Value;
            value = app.Input1Switch.Value;
            value2 = app.Input2Switch.Value;
%Legend Lamp
            if strcmpi(val, 'On')==1 && strcmpi(value,'On') && strcmpi(value2,'Off')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value,'Off') && strcmpi(value2,'On')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value2,'On') && strcmpi(value,'On')
                app.Lamp3.Color = 'g';

                
            else
                app.Lamp3.Color = 'r';  
                   
            end             
            plot(x,y,a,b,'parent',app.UIAxes)
        end

        % Callback function
        function TextAreaValueChanged(app, event)
            
        end

        % Value changed function: TitleEditField
        function TitleEditFieldValueChanged(app, event)
            %value = app.TitleEditField.Value;
            
        end

        % Value changed function: Input1Switch
        function Input1SwitchValueChanged(app, event)
            val = app.GraphSwitch.Value;
            value = app.Input1Switch.Value;
            value2 = app.Input2Switch.Value;
                      
            disp1 = app.Button.Value;                    
            disp2 = app.Button_2.Value;   
            disp3 = app.Button_3.Value; 
              
            x=app.XEditField.Value;
            y=app.YEditField.Value;
            
            a=app.XEditField_2.Value;
            b=app.YEditField_2.Value;
%Legend Lamp
            if strcmpi(val, 'On')==1 && strcmpi(value,'On') && strcmpi(value2,'Off')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value,'Off') && strcmpi(value2,'On')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value2,'On') && strcmpi(value,'On')
                app.Lamp3.Color = 'g';

                
            else
                app.Lamp3.Color = 'r';  
                   
            end             
            
%switchlight
            if strcmpi(value, 'On')==1
                app.Lamp_2.Color = 'g';
            else
                app.Lamp_2.Color = 'r';  
                   
            end   
            
%disp1
            if strcmpi(value,'On')==1 && disp1==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])
            
            elseif strcmpi(value,'On')==1 && disp1==true && strcmpi(value2,'Off')==1 && strcmpi(val, 'On')==1
            plot(x,y,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])                
            
            elseif strcmpi(value,'Off')==1 && disp1==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])  
            
%disp2
            elseif strcmpi(value,'On')==1 && disp2==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])
            
            elseif strcmpi(value,'On')==1 && disp2==true && strcmpi(value2,'Off')==1 && strcmpi(val, 'On')==1
            plot(x,y,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])                
            
            elseif strcmpi(value,'Off')==1 && disp2==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])       

%disp3
            elseif strcmpi(value,'On')==1 && disp3==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(x,y,a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])
            
            elseif strcmpi(value,'On')==1 && disp3==true && strcmpi(value2,'Off')==1 && strcmpi(val, 'On')==1
            plot(x,y,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])                
            
            elseif strcmpi(value,'Off')==1 && disp3==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])  
            else 
                plot(x,y,a,b,'parent',app.UIAxes)
            end
          
        end

        % Value changed function: Input2Switch
        function Input2SwitchValueChanged(app, event)
            val = app.GraphSwitch.Value;
            value = app.Input1Switch.Value;
            value2 = app.Input2Switch.Value;
                      
            disp1 = app.Button.Value;                    
            disp2 = app.Button_2.Value;   
            disp3 = app.Button_3.Value; 
              
            x=app.XEditField.Value;
            y=app.YEditField.Value;
            
            a=app.XEditField_2.Value;
            b=app.YEditField_2.Value;
%Legend Lamp
            if strcmpi(val, 'On')==1 && strcmpi(value,'On') && strcmpi(value2,'Off')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value,'Off') && strcmpi(value2,'On')
                app.Lamp3.Color = 'g';
            elseif strcmpi(val, 'On')==1 && strcmpi(value2,'On') && strcmpi(value,'On')
                app.Lamp3.Color = 'g';

                
            else
                app.Lamp3.Color = 'r';  
                   
            end             
            
%switchlamp
            if strcmpi(value2, 'On')==1
                app.Lamp2.Color = 'g';
            else
                app.Lamp2.Color = 'r';  
                   
            end 
%disp1
            if strcmpi(value,'On')==1 && disp1==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])
            
            elseif strcmpi(value,'On')==1 && disp1==true && strcmpi(value2,'Off')==1 && strcmpi(val, 'On')==1
            plot(x,y,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])                
            
            elseif strcmpi(value,'Off')==1 && disp1==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(a,b,'parent',app.UIAxes,"Marker","*","Color", [1.00,0.00,0.00])  
            
%disp2
            elseif strcmpi(value,'On')==1 && disp2==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(x,y,a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])
            
            elseif strcmpi(value,'On')==1 && disp2==true && strcmpi(value2,'Off')==1 && strcmpi(val, 'On')==1
            plot(x,y,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])                
            
            elseif strcmpi(value,'Off')==1 && disp2==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(a,b,'parent',app.UIAxes,"Marker","+","Color", [0.00,0.00,1.00])       

%disp3
            elseif strcmpi(value,'On')==1 && disp3==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(x,y,a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])
            
            elseif strcmpi(value,'On')==1 && disp3==true && strcmpi(value2,'Off')==1 && strcmpi(val, 'On')==1
            plot(x,y,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])                
            
            elseif strcmpi(value,'Off')==1 && disp3==true && strcmpi(value2,'On')==1 && strcmpi(val, 'On')==1
            plot(a,b,'parent',app.UIAxes,"Marker",".","Color", [1.00,0.00,1.00])  
            else 
                plot(x,y,a,b,'parent',app.UIAxes)
            end
          
          
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.GraphSwitchLabel = uilabel(app.UIFigure);
            app.GraphSwitchLabel.Text = 'Graph';
            app.GraphSwitch = uiswitch(app.UIFigure, 'slider');
            app.GraphSwitch.ValueChangedFcn = createCallbackFcn(app, @GraphSwitchValueChanged, true);
            app.GraphSwitch.Value = 'On';
            app.Lamp = uilamp(app.UIFigure);
            app.LegendButtonGroup = uibuttongroup(app.UIFigure);
            app.LegendButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LegendButtonGroupSelectionChanged, true);
            app.LegendButtonGroup.Title = 'Legend';
            app.Button = uiradiobutton(app.LegendButtonGroup);
            app.Button.Text = '*';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.LegendButtonGroup);
            app.Button_2.Text = '+';
            app.Button_3 = uiradiobutton(app.LegendButtonGroup);
            app.Button_3.Text = '-';
            app.Lamp3 = uilamp(app.LegendButtonGroup);
            app.ClearGraphButton = uibutton(app.UIFigure, 'push');
            app.ClearGraphButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGraphButtonPushed, true);
            app.ClearGraphButton.Text = 'Clear Graph';
            app.XAxisEditField = uieditfield(app.UIFigure, 'text');
            app.XAxisEditField.Value = 'X-Axis';
            app.YAxisEditField = uieditfield(app.UIFigure, 'text');
            app.YAxisEditField.Value = 'Y-Axis';
            app.TitleEditField = uieditfield(app.UIFigure, 'text');
            app.TitleEditField.ValueChangedFcn = createCallbackFcn(app, @TitleEditFieldValueChanged, true);
            app.TitleEditField.Value = 'Title';
            app.XEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.YEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Input1SwitchLabel = uilabel(app.UIFigure);
            app.Input1SwitchLabel.Text = 'Input 1';
            app.Input1Switch = uiswitch(app.UIFigure, 'slider');
            app.Input1Switch.ValueChangedFcn = createCallbackFcn(app, @Input1SwitchValueChanged, true);
            app.Input1Switch.Value = 'On';
            app.Input2SwitchLabel = uilabel(app.UIFigure);
            app.Input2SwitchLabel.Text = 'Input 2';
            app.Input2Switch = uiswitch(app.UIFigure, 'slider');
            app.Input2Switch.ValueChangedFcn = createCallbackFcn(app, @Input2SwitchValueChanged, true);
            app.Input2Switch.Value = 'On';
            app.Lamp_2 = uilamp(app.UIFigure);
            app.Lamp2 = uilamp(app.UIFigure);
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'X';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.Value = 'Y';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.Value = 'X';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.Value = 'Y';
            app.EditableLabel = uilabel(app.UIFigure);
            app.EditableLabel.Text = '(Editable)';
            app.EditableLabel_2 = uilabel(app.UIFigure);
            app.EditableLabel_2.Text = '(Editable)';
            app.EditableLabel_3 = uilabel(app.UIFigure);
            app.EditableLabel_3.Text = '(Editable)';
            app.EditableLabel_4 = uilabel(app.UIFigure);
            app.EditableLabel_4.Text = '(Editable)';
            app.EditableLabel_5 = uilabel(app.UIFigure);
            app.EditableLabel_5.Text = '(Editable)';
            app.EditableLabel_6 = uilabel(app.UIFigure);
            app.EditableLabel_6.Text = '(Editable)';
            app.EditableLabel_7 = uilabel(app.UIFigure);
            app.EditableLabel_7.Text = '(Editable)';
            app.NOTEAllSwitchesmuchbeintheOnpositionwhenchangingLegendLabel = uilabel(app.UIFigure);
            app.NOTEAllSwitchesmuchbeintheOnpositionwhenchangingLegendLabel.Text = {'NOTE: Graph switch and atleast 1 Input switch much be in the ''On" position when changing Legend'; 'Green light in the Legend indicated "Safe" to modify.'};
            app.UIAxes = uiaxes(app.UIFigure);
            zlabel(app.UIAxes, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Final_Project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end